classdef NewportTlbScan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NewportTlbScanUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScanTab                         matlab.ui.container.Tab
        SignalAxes                      matlab.ui.control.UIAxes
        ScopechannelsPanel              matlab.ui.container.Panel
        SignalDropDownLabel             matlab.ui.control.Label
        SignalDropDown                  matlab.ui.control.DropDown
        WavelengthoutDropDownLabel      matlab.ui.control.Label
        WavelengthoutDropDown           matlab.ui.control.DropDown
        ScanPanel                       matlab.ui.container.Panel
        StartButton                     matlab.ui.control.Button
        StartwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StartwlnmEditField              matlab.ui.control.NumericEditField
        StopwavelengthnmEditFieldLabel  matlab.ui.control.Label
        StopwlnmEditField               matlab.ui.control.NumericEditField
        LasertostartwlButton            matlab.ui.control.Button
        SpeednmsEditFieldLabel          matlab.ui.control.Label
        SpeednmsEditField               matlab.ui.control.NumericEditField
        StopButton                      matlab.ui.control.Button
        MaxspeedLabel                   matlab.ui.control.Label
        RequireswavelengthtrackonLabel  matlab.ui.control.Label
        HelpTab                         matlab.ui.container.Tab
        Label                           matlab.ui.control.Label
    end


    properties (Access = public)
        GuiSync
        
        % Newport diode laser object
        Laser
        
        laser_name
        
        % Oscilloscope instrument object
        Scope 
        
        scope_name

        % Signal versus time trace
        SigVsTimeTrace
        
        % Wavelength vs time trace
        WlVsTimeTrace
        
        % Signal versus Wavelength. This is the trace that is transferred to Daq
        Trace
    end
    
    properties (SetAccess = private, GetAccess = public)
        
        % Private copy of InstrumentList
        InstrumentList
        ScanTimer
    end
    
    properties (Access = private)
        Metadata = MyMetadata.empty()
    end
    
    events
        NewData
    end
    
    methods (Access = public)
        function Mdt = readSettings(app)
            if isempty(app.Metadata)
                app.Metadata = MyMetadata('title', 'NewportTlbScan');
                addParam(app.Metadata, 'laser', '');
                addParam(app.Metadata, 'scope', '');
            end
            
            app.Metadata.ParamList.laser = app.laser_name;
            app.Metadata.ParamList.scope = app.scope_name;
            
            Mdt = copy(MyMetadata);
        end
    end

    methods (Access = private)
        
        % Lineked control elements are updated by calling updateGui(app)
        function createLinks(app)
            addLink(app.GuiSync, app.StartwlnmEditField, 'Laser.scan_start_wl');           
            addLink(app.GuiSync, app.StopwlnmEditField, 'Laser.scan_stop_wl');           
            addLink(app.GuiSync, app.SpeednmsEditField, 'Laser.scan_speed');
            addLink(app.GuiSync, app.MaxspeedLabel, 'Laser.scan_speed_max', ...
                'elem_prop', 'Text', ...
                'outputProcessingFcn', @(x)sprintf('Max speed: %.1f nm/s', x));
        end
    
        function parseInputs(app, varargin)
            p = inputParser();
            
            addParameter(p,'laser_name','');
            addParameter(p,'scope_name','');
            
            parse(p,varargin{:});
            
            if ~ismember('scope_name', p.UsingDefaults)
                app.scope_name = p.Results.scope_name;
                app.Scope = runInstrument(p.Results.scope_name, 'enable_gui', true);
            end
            
            if ~ismember('laser_name', p.UsingDefaults)
                app.laser_name = p.Results.laser_name;
                app.Laser = runInstrument(p.Results.laser_name, 'enable_gui', true);
            end
        end
    
        function acquireScanResult(app)
        
            % Unblock the scan panel
            updateScanPanelAccess(app,false);
            
            L = app.Laser;
            S = app.Scope;
            
            % Read signal vs time
            sig_ch = app.SignalDropDown.Value;
            S.channel = sig_ch;
            readTrace(S);
            app.SigVsTimeTrace = copy(S.Trace);
            
            % Read wavelength voltage vs time
            S.channel = app.WavelengthoutDropDown.Value;
            readTrace(S);
            app.WlVsTimeTrace = copy(S.Trace);
            
            tag = sprintf('scale%i', sig_ch);
                
            % For Dpo scopes v<v_min_lim if the trace is aquired partially
            v_min_lim = -5*S.(tag);
            v_max_lim = 5*S.(tag);
            sel_ind = arrayfun(@(x)(x>v_min_lim && x<v_max_lim), app.SigVsTimeTrace.y);
            app.Trace.y = app.SigVsTimeTrace.y(sel_ind);
            
            % Calibrate wavelength from voltage in the auxiliary channel
            v_min = min(app.WlVsTimeTrace.y(sel_ind));
            v_max = max(app.WlVsTimeTrace.y(sel_ind));
            sc = (L.scan_stop_wl-L.scan_start_wl)/(v_max-v_min);
            app.Trace.x = (app.WlVsTimeTrace.y(sel_ind)-v_min)*sc+L.scan_start_wl;
            
            try
                plot(app.Trace, app.SignalAxes);
            catch
                warning('Cannot plot trace.');
            end
            
            % Trigger event to transfer data
            notify(app, 'NewData');
        end
        
        function updateScopeChannelDropDowns(app)
            ch_arr = 1:app.Scope.channel_no;
                
            app.SignalDropDown.Items = arrayfun(@(x)sprintf('%i',x), ch_arr,...
                'UniformOutput', false);
            app.SignalDropDown.ItemsData = ch_arr;
            
            app.WavelengthoutDropDown.Items = arrayfun(@(x)sprintf('%i',x), ch_arr,...
                'UniformOutput', false);
            app.WavelengthoutDropDown.ItemsData = ch_arr;
        end
    
        function updateScanPanelAccess(app, is_scanning)
            if is_scanning
                app.StartButton.Enable = 'off';
                app.StartwlnmEditField.Enable = 'off';
                app.StopwlnmEditField.Enable = 'off';
                app.LasertostartwlButton.Enable = 'off';
                app.SpeednmsEditField.Enable = 'off';
            else
                app.StartButton.Enable = 'on';
                app.StartwlnmEditField.Enable = 'on';
                app.StopwlnmEditField.Enable = 'on';
                app.LasertostartwlButton.Enable = 'on';
                app.SpeednmsEditField.Enable = 'on';
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.GuiSync = MyGuiSync(app);
            
            % Loads parsed variables into class properties
            parseInputs(app, varargin{:});
            
            createLinks(app);
            
            % Create an empty signal-vs-wavelength trace
            app.Trace = MyTrace( ...
                'name_x',   'Wavelength', ...
                'unit_x',   'nm', ...
                'name_y',   'Voltage', ...
                'unit_y',   'V');
            
            % Fill dopdowns with local instrument names
            updateScopeChannelDropDowns(app);
            
            app.ScanTimer = timer();
            app.ScanTimer.ExecutionMode = 'singleShot';
            app.ScanTimer.TimerFcn = @(~,~)acquireScanResult(app);
            
            addToCleanup(app.GuiSync, app.ScanTimer);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            L = app.Laser;
            S = app.Scope;
            
            % Estimated scan time (s)
            scan_time = (L.scan_stop_wl-L.scan_start_wl)/L.scan_speed; 
            
            % Adjust scope span accordingly
            % time_scale (s/div), overall scan length is 10 divisions
            S.time_scale = scan_time/10;

            if S.time_scale*10 < scan_time
                
                % Some scopes do not accept arbitrary time scale.
                % If time scale was rounded to smaller one, increase by the factor of 2
                S.time_scale = 2*scan_time/10;
            end
            
            configSingleScan(L)
            
            % Initite laser scan and trigger 
            % signal acquisition by the the scope 
            startScan(L);
            
            % Trigger the scope for single acquisition
            acquireSingle(S);
            
            % Start timer that executes only once when the nominal 
            % scan time is elapced
            app.ScanTimer.StartDelay = round(scan_time, 3);
            
            start(app.ScanTimer);
            updateScanPanelAccess(app,true);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            try
                stopScan(app.Laser);
                stop(app.ScanTimer);
                updateScanPanelAccess(app,false);
            catch
                warning('Unable to stop laser scan')
            end
        end

        % Button pushed function: LasertostartwlButton
        function LasertostartwlButtonPushed(app, event)
            app.Laser.wavelength_sp = app.Laser.scan_start_wl;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NewportTlbScanUIFigure = uifigure('Visible', 'off');
            app.NewportTlbScanUIFigure.Name = 'Diode laser scan';
            app.TabGroup = uitabgroup(app.NewportTlbScanUIFigure);
            app.ScanTab = uitab(app.TabGroup);
            app.ScanTab.Title = 'Scan';
            app.SignalAxes = uiaxes(app.ScanTab);
            xlabel(app.SignalAxes, 'Wavelength (nm)')
            ylabel(app.SignalAxes, 'V')
            app.ScopechannelsPanel = uipanel(app.ScanTab);
            app.ScopechannelsPanel.Title = 'Scope channels';
            app.SignalDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.SignalDropDownLabel.Text = 'Signal';
            app.SignalDropDown = uidropdown(app.ScopechannelsPanel);
            app.SignalDropDown.Items = {'1', '2'};
            app.SignalDropDown.Value = '1';
            app.WavelengthoutDropDownLabel = uilabel(app.ScopechannelsPanel);
            app.WavelengthoutDropDownLabel.Text = 'Wavelength out';
            app.WavelengthoutDropDown = uidropdown(app.ScopechannelsPanel);
            app.WavelengthoutDropDown.Items = {'1', '2'};
            app.WavelengthoutDropDown.Value = '2';
            app.ScanPanel = uipanel(app.ScanTab);
            app.ScanPanel.Title = 'Scan';
            app.StartButton = uibutton(app.ScanPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StartwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StartwavelengthnmEditFieldLabel.Text = 'Start wavelength (nm)';
            app.StartwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StartwlnmEditField.ValueDisplayFormat = '%.12g';
            app.StopwavelengthnmEditFieldLabel = uilabel(app.ScanPanel);
            app.StopwavelengthnmEditFieldLabel.Text = 'Stop wavelength (nm)';
            app.StopwlnmEditField = uieditfield(app.ScanPanel, 'numeric');
            app.StopwlnmEditField.ValueDisplayFormat = '%.12g';
            app.LasertostartwlButton = uibutton(app.ScanPanel, 'push');
            app.LasertostartwlButton.ButtonPushedFcn = createCallbackFcn(app, @LasertostartwlButtonPushed, true);
            app.LasertostartwlButton.Text = 'Laser to start wl';
            app.SpeednmsEditFieldLabel = uilabel(app.ScanPanel);
            app.SpeednmsEditFieldLabel.Text = 'Speed (nm/s)';
            app.SpeednmsEditField = uieditfield(app.ScanPanel, 'numeric');
            app.SpeednmsEditField.ValueDisplayFormat = '%.12g';
            app.StopButton = uibutton(app.ScanPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.MaxspeedLabel = uilabel(app.ScanPanel);
            app.MaxspeedLabel.Text = 'Max speed: 0.0 nm/s';
            app.RequireswavelengthtrackonLabel = uilabel(app.ScanPanel);
            app.RequireswavelengthtrackonLabel.Text = 'Requires wavelength track on';
            app.HelpTab = uitab(app.TabGroup);
            app.HelpTab.Title = 'Help';
            app.Label = uilabel(app.HelpTab);
            app.Label.Text = {'This program scans wavelength of ECDL laser and records transmission vs wavelength'; 'Photodiode and Wavelength output of ECDL need to be connected to oscilloscope channels'; ''; 'It is preferable to perform laser scan in constant current mode, or otherwise set scan speed to be <1% of max scan speed '};
            % Show the figure after all components are created
            app.NewportTlbScanUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewportTlbScan(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NewportTlbScanUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NewportTlbScanUIFigure)
        end
    end
end